classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GitrohmbngphngphpTaylorLabel    matlab.ui.control.Label
        GitrohmbngphngphpLagrangeLabel  matlab.ui.control.Label
        athcnisuyLagrangeLabel          matlab.ui.control.Label
        dathucnoisuyLagrangeLabel       matlab.ui.control.Label
        giatridaohambangphuongphapLagrangeLabel  matlab.ui.control.Label
        giatridaohambangphuongphapTaylorLabel  matlab.ui.control.Label
        TnhButton                       matlab.ui.control.Button
        giatrixmataidocantinhdaohamEditField  matlab.ui.control.NumericEditField
        GitrxmticntnhohmLabel           matlab.ui.control.Label
        manggomcacgiatriytuongungEditField  matlab.ui.control.EditField
        mnggmccgitrytngngLabel          matlab.ui.control.Label
        manggomcacgiatrixEditField      matlab.ui.control.EditField
        MnggmccgitrxLabel               matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: TnhButton
        function TnhButtonPushed(app, event)
            x = str2num(app.manggomcacgiatrixEditField.Value);
            y = str2num(app.manggomcacgiatriytuongungEditField.Value);
            xi = app.giatrixmataidocantinhdaohamEditField.Value;          
            N = length(x);
            for i = 1:N
                if x(i) == xi
                    N = i;
                    break;
                end 
            end
            kq = double((y(N+1)-y(N))/(x(N+1)-x(N)));
            dy = LagrangeDerivative(x, y, xi);
            fy = LagrangeD(x, y);            
            app.giatridaohambangphuongphapTaylorLabel.Text = num2str(kq);
            app.giatridaohambangphuongphapLagrangeLabel.Text = num2str(dy);
            app.dathucnoisuyLagrangeLabel.Text = char(fy);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MnggmccgitrxLabel = uilabel(app.UIFigure);
            app.MnggmccgitrxLabel.Text = 'Mảng gồm các giá trị x;';
            app.manggomcacgiatrixEditField = uieditfield(app.UIFigure, 'text');
            app.manggomcacgiatrixEditField.Value = '[0.1, 0.3, 0.5, 0.7, 0.9]';
            app.mnggmccgitrytngngLabel = uilabel(app.UIFigure);
            app.mnggmccgitrytngngLabel.Text = 'Mảng gồm các giá trị y tương ứng:';
            app.manggomcacgiatriytuongungEditField = uieditfield(app.UIFigure, 'text');
            app.manggomcacgiatriytuongungEditField.Value = '[0.1002, 0.3047, 0.5236, 0.7754, 1.1198]';
            app.GitrxmticntnhohmLabel = uilabel(app.UIFigure);
            app.GitrxmticntnhohmLabel.Text = 'Giá trị x mà tại đó cần tính đạo hàm:';
            app.giatrixmataidocantinhdaohamEditField = uieditfield(app.UIFigure, 'numeric');
            app.giatrixmataidocantinhdaohamEditField.Value = 0.7;
            app.TnhButton = uibutton(app.UIFigure, 'push');
            app.TnhButton.ButtonPushedFcn = createCallbackFcn(app, @TnhButtonPushed, true);
            app.TnhButton.Text = 'Tính';
            app.giatridaohambangphuongphapTaylorLabel = uilabel(app.UIFigure);
            app.giatridaohambangphuongphapTaylorLabel.Text = '00';
            app.giatridaohambangphuongphapLagrangeLabel = uilabel(app.UIFigure);
            app.giatridaohambangphuongphapLagrangeLabel.Text = '00';
            app.dathucnoisuyLagrangeLabel = uilabel(app.UIFigure);
            app.dathucnoisuyLagrangeLabel.Text = '00';
            app.athcnisuyLagrangeLabel = uilabel(app.UIFigure);
            app.athcnisuyLagrangeLabel.Text = 'Đa thức nội suy Lagrange:';
            app.GitrohmbngphngphpLagrangeLabel = uilabel(app.UIFigure);
            app.GitrohmbngphngphpLagrangeLabel.Text = 'Giá trị đạo hàm bằng phương pháp Lagrange:';
            app.GitrohmbngphngphpTaylorLabel = uilabel(app.UIFigure);
            app.GitrohmbngphngphpTaylorLabel.Text = 'Giá trị đạo hàm bằng phương pháp Taylor:';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end